classdef Thermal_Vest_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExportCSVButton                 matlab.ui.control.Button
        StatusLabel_3                   matlab.ui.control.Label
        StatusLamp_3                    matlab.ui.control.Lamp
        StatusLabel_2                   matlab.ui.control.Label
        StatusLamp_2                    matlab.ui.control.Lamp
        PortDropDown                    matlab.ui.control.DropDown
        PortDropDownLabel               matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
        CoreTemperatureCEditField       matlab.ui.control.NumericEditField
        CoreTemperatureCEditFieldLabel  matlab.ui.control.Label
        DesiredVestTemperatureCEditField  matlab.ui.control.NumericEditField
        DesiredVestTemperatureCLabel    matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Properties that correspond to variables
    properties (Access = private)
        s               % Serial object
        isConnected     % Boolean indicating if Arduino is connected

        waterTemp       % X-axis data for plot
        %waterTemp2       % X-axis data for plot
        s1Temp          % Sensor 1 temperature
        s2Temp          % Sensor 2 temperature
        s3Temp          % Sensor 3 temperature
        s4Temp          % Sensor 4 temperature
        s5Temp          % Sensor 5 temperature
        s6Temp          % Sensor 6 temperature
        s7Temp          % Sensor 7 temperature
        coreTemp        % Core temperature

        yTime           % Y-axis data for plot
        %yTime2           % Y-axis data for plot
        plotLine        % Handle to plot line object
        plotLine2        % Handle to plot line object
        plotLine3       % Handle to plot line object
        plotLine4        % Handle to plot line object
        plotLine5        % Handle to plot line object
        plotLine6        % Handle to plot line object
        plotLine7        % Handle to plot line object
        plotLine8        % Handle to plot line object
        plotLine9        % Handle to plot line object

        dataStor

        wsindex=1;
        flag=0;
    end

    % Callbacks for App components
    methods (Access = private)

        % Button pushed function: ConnectButton
        %         function ConnectButtonPushed(app, event)
        %             if ~app.isConnected
        %                 % Open the serial port and configure it
        %                 app.s = serialport("COM4", 115200);
        %                 configureTerminator(app.s, "CR/LF");
        %
        %                 % Update status
        %                 app.StatusLabel.Text = "Connected";
        %                 app.StatusLamp.Color = 'g';
        %
        %                 % Start reading and plotting data
        %                 app.startReadData();
        %
        %                 % Update isConnected property
        %                 app.isConnected = true;
        %
        %             end
        %         end

        % Button pushed function: DisconnectButton
        %         function DisconnectButtonPushed(app, event)
        %             if app.isConnected
        %                 % Close the serial port
        %                 close(ancestor(app.s, 'figure'));
        %
        %
        %
        %                 % Update status
        %                 app.StatusLabel.Text = "Disconnected";
        %                 app.StatusLamp.Color = 'r';
        %
        %                 % Update isConnected property
        %                 app.isConnected = false;
        %             end
        %         end
    end

    % Methods that run on startup
    methods (Access = private)

        % Read and plot data in a loop
        function startReadData(app)

            while isvalid(app)
                % Read data from the serial port
                
                try
                    data = readline(app.s);
%                     disp(length(data))

                    % Split the data into two parts (X and Y)
                    xandy = split(data, ",");

                    % Convert the data to numeric values
                    currentTemp = str2double(xandy(1));

                    s1 = str2double(xandy(2));
                    s2 = str2double(xandy(3));
                    s3 = str2double(xandy(4));
                    s4 = str2double(xandy(5));
                    s5 = str2double(xandy(6));
                    s6 = str2double(xandy(7));
                    s7 = str2double(xandy(8));

                    setpoint = str2double(xandy(9));

                    cellstate1 = str2double(xandy(10));
                    cellstate2 = str2double(xandy(11));

                    coreT = str2double(xandy(12));

                    time = str2double(xandy(13));

                    % Store data


                    app.dataStor(end+1,:) = [time,currentTemp,coreT,s1,s2,s3,s4,s5,s6,s7,cellstate1,cellstate2,setpoint];
%                     disp(app.dataStor);

                    % Add the data to the plot

                    app.waterTemp = [app.waterTemp(2:end), currentTemp];
                    app.s1Temp = [app.s1Temp(2:end), s1];
                    app.s2Temp = [app.s2Temp(2:end), s2];
                    app.s3Temp = [app.s3Temp(2:end), s3];
                    app.s4Temp = [app.s4Temp(2:end), s4];
                    app.s5Temp = [app.s5Temp(2:end), s5];
                    app.s6Temp = [app.s6Temp(2:end), s6];
                    app.s7Temp = [app.s7Temp(2:end), s7];
                    app.coreTemp = [app.coreTemp(2:end), coreT];
                    %app.waterTemp(end+1) = currentTemp;

%                     app.s1Temp(end+1) = s1;
%                     app.s2Temp(end+1) = s2;
%                     app.s3Temp(end+1) = s3;
%                     app.s4Temp(end+1) = s4;
%                     app.s5Temp(end+1) = s5;
%                     app.s6Temp(end+1) = s6;
%                     app.s7Temp(end+1) = s7;

%                     app.coreTemp(end+1) = coreT;

%                     app.yTime(end+1) = time;
                    app.yTime = [app.yTime(2:end), time];
%                     disp(app.yTime2);
%                     disp(app.waterTemp);

                    % Update the plot
%                     plot(app.UIAxes, app.yTime2, app.waterTemp2)
                    %disp([min(app.yTime2),max(app.yTime2)])
                    timeMin = max([0,time-10]);
                    timeMax = max([1,time]);

                    set(app.plotLine,'xdata',app.yTime,'ydata',app.waterTemp);
                    set(app.UIAxes,'Xlim',[timeMin,timeMax]);
                    app.UIAxes.XTick = [timeMin,0.5*(timeMin + timeMax),timeMax];

                    set(app.plotLine2,'xdata',app.yTime,'ydata',app.s1Temp);
                    set(app.UIAxes_2,'Xlim',[timeMin,timeMax]);
                    app.UIAxes_2.XTick = [timeMin,0.5*(timeMin + timeMax),timeMax];

                    set(app.plotLine3,'xdata',app.yTime,'ydata',app.s2Temp);
                    set(app.UIAxes_3,'Xlim',[timeMin,timeMax]);
                    app.UIAxes_3.XTick = [timeMin,0.5*(timeMin + timeMax),timeMax];

                    set(app.plotLine4,'xdata',app.yTime,'ydata',app.s3Temp);
                    set(app.UIAxes_4,'Xlim',[timeMin,timeMax]);
                    app.UIAxes_4.XTick = [timeMin,0.5*(timeMin + timeMax),timeMax];

                    set(app.plotLine5,'xdata',app.yTime,'ydata',app.s4Temp);
                    set(app.UIAxes_5,'Xlim',[timeMin,timeMax]);
                    app.UIAxes_5.XTick = [timeMin,0.5*(timeMin + timeMax),timeMax];

                    set(app.plotLine6,'xdata',app.yTime,'ydata',app.s5Temp);
                    set(app.UIAxes_6,'Xlim',[timeMin,timeMax]);
                    app.UIAxes_6.XTick = [timeMin,0.5*(timeMin + timeMax),timeMax];

                    set(app.plotLine7,'xdata',app.yTime,'ydata',app.s6Temp);
                    set(app.UIAxes_7,'Xlim',[timeMin,timeMax]);
                    app.UIAxes_7.XTick = [timeMin,0.5*(timeMin + timeMax),timeMax];

                    set(app.plotLine8,'xdata',app.yTime,'ydata',app.s7Temp);
                    set(app.UIAxes_8,'Xlim',[timeMin,timeMax]);
                    app.UIAxes_8.XTick = [timeMin,0.5*(timeMin + timeMax),timeMax];

                    set(app.plotLine9,'xdata',app.yTime,'ydata',app.coreTemp);
                    set(app.UIAxes_9,'Xlim',[timeMin,timeMax]);
                    app.UIAxes_9.XTick = [timeMin,0.5*(timeMin + timeMax),timeMax];

%                     plot(app.UIAxes_2, app.yTime, app.s1Temp)
%                     plot(app.UIAxes_3, app.yTime, app.s2Temp)
%                     plot(app.UIAxes_4, app.yTime, app.s3Temp)
%                     plot(app.UIAxes_5, app.yTime, app.s4Temp)
%                     plot(app.UIAxes_6, app.yTime, app.s5Temp)
%                     plot(app.UIAxes_7, app.yTime, app.s6Temp)
%                     plot(app.UIAxes_8, app.yTime, app.s7Temp)
%                     plot(app.UIAxes_9, app.yTime, app.coreTemp)

                    app.CoreTemperatureCEditField.Value = coreT;
                    app.DesiredVestTemperatureCEditField.Value = setpoint;
                    % Pause for a short period of time to prevent overloading the serial port

                    if(cellstate1 == 1)
                        app.StatusLamp_2.Color = 'g';
                    end
                    if(cellstate1 == 0)
                        app.StatusLamp_2.Color = 'r';
                    end

                    if(cellstate2 == 1)
                        app.StatusLamp_3.Color = 'g';
                    end
                    if(cellstate2 == 0)
                        app.StatusLamp_3.Color = 'r';
                    end

                    if(app.flag == 1)
                        formatOut = 'mm-dd-yy(HH_MM_ss)';
                        t = datestr(now,formatOut);
                        proj = 'CoolingVestData';
                        filetype = '.csv';
                        fileName = strcat(t,proj,filetype);
                        dataTable = array2table(app.dataStor);
                        dataTable.Properties.VariableNames = {'Time (s)','Water temperature (C)','Core Temperature (C)','Sensor 1 (C)','Sensor 2 (C)','Sensor 3 (C)','Sensor 4 (C)','Sensor 5 (C)','Sensor 6 (C)','Sensor 7 (C)','Large Cell','Small Cell','Setpoint'};
                        writetable(dataTable,fileName);
                        app.flag = 0;
                    end
                    pause(0.01);
                catch
                    break;
                end
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize properties
            app.isConnected = false;
            app.waterTemp = zeros(1,10);
            app.s1Temp = zeros(1,10);
            app.s2Temp = zeros(1,10);
            app.s3Temp = zeros(1,10);
            app.s4Temp = zeros(1,10);
            app.s5Temp = zeros(1,10);
            app.s6Temp = zeros(1,10);
            app.s7Temp = zeros(1,10);
            app.coreTemp = zeros(1,10);
%             app.yTime = [];
            app.yTime = zeros(1,10);
            
            serialPorts = serialportlist("available");
            app.PortDropDown.Items=serialPorts;

            % Create the plot
            app.plotLine = plot(app.UIAxes, app.yTime, app.waterTemp);
            app.plotLine2 = plot(app.UIAxes_2, app.yTime, app.s1Temp);
            app.plotLine3 = plot(app.UIAxes_3, app.yTime, app.s2Temp);
            app.plotLine4 = plot(app.UIAxes_4, app.yTime, app.s3Temp);
            app.plotLine5 = plot(app.UIAxes_5, app.yTime, app.s4Temp);
            app.plotLine6 = plot(app.UIAxes_6, app.yTime, app.s5Temp);
            app.plotLine7 = plot(app.UIAxes_7, app.yTime, app.s6Temp);
            app.plotLine8 = plot(app.UIAxes_8, app.yTime, app.s7Temp);
            app.plotLine9 = plot(app.UIAxes_9, app.yTime, app.coreTemp);
        end

        % Value changed function: DesiredVestTemperatureCEditField
        function DesiredVestTemperatureCEditFieldValueChanged(app, event)
            value = app.DesiredVestTemperatureCEditField.Value;
            valueStr = sprintf('%.6f', value);
            msg = strcat('0', valueStr);
            write(app.s, msg, "string");

        end

        % Value changed function: CoreTemperatureCEditField
        function CoreTemperatureCEditFieldValueChanged(app, event)
            %value = app.CoreTemperatureCEditField.Value;

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if isvalid(app)

                app.wsindex=1;
                openport=app.PortDropDown.Value;

                if ~app.isConnected
                    % Open the serial port and configure it
                    app.s = serialport(openport, 115200);
                    configureTerminator(app.s, "CR/LF");

                    % Update status
                    app.StatusLabel.Text = "Connected";

                    % Start reading and plotting data
                    app.startReadData();
                    disp(123123);
                    % Update isConnected property
                    if isvalid(app)
                        app.isConnected = true;
                    end

                end
            end
        end

        % Callback function
        function DisconnectButtonPushed(app, event)
            if app.isConnected
                % Close the serial port
                close(ancestor(app.s, 'figure'));

                % Update status
                app.StatusLabel.Text = "Disconnected";
                app.StatusLamp.Color = 'r';

                % Update isConnected property
                app.isConnected = false;
            end
        end

        % Callback function
        function PcoefficientEditFieldValueChanged(app, event)
            value = app.PcoefficientEditField.Value;
            valueStr = sprintf('%.6f', value);
            msg = strcat('1', valueStr);
            write(app.s, msg, "string");
        end

        % Callback function
        function IcoefficientEditFieldValueChanged(app, event)
            value = app.IcoefficientEditField.Value;
            valueStr = sprintf('%.6f', value);
            msg = strcat('2', valueStr);
            write(app.s, msg, "string");
        end

        % Callback function
        function DcoefficientEditFieldValueChanged(app, event)
            value = app.DcoefficientEditField.Value;
            valueStr = sprintf('%.6f', value);
            msg = strcat('3', valueStr);
            write(app.s, msg, "string");
        end

        % Value changed function: PortDropDown
        function PortDropDownValueChanged(app, event)
            %value = app.PortDropDown.Value;
        end

        % Button pushed function: ExportCSVButton
        function onDataLoggingExportCSVButtonClicked(app, event)
            app.flag = 1;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.isConnected = 0;
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Water Temperature')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Temp (C)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [10 40];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Sensor 1')
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'Temp (C)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.YLim = [10 40];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Sensor 2')
            xlabel(app.UIAxes_3, 'Time (s)')
            ylabel(app.UIAxes_3, 'Temp (C)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.YLim = [10 40];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Sensor 3')
            xlabel(app.UIAxes_4, 'Time (s)')
            ylabel(app.UIAxes_4, 'Temp (C)')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.YLim = [10 40];
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Sensor 4')
            xlabel(app.UIAxes_5, 'Time (s)')
            ylabel(app.UIAxes_5, 'Temp (C)')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.YLim = [10 40];
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Sensor 5')
            xlabel(app.UIAxes_6, 'Time (s)')
            ylabel(app.UIAxes_6, 'Temp (C)')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.YLim = [10 40];
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'Sensor 6')
            xlabel(app.UIAxes_7, 'Time (s)')
            ylabel(app.UIAxes_7, 'Temp (C)')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_7.YLim = [10 40];
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'Sensor 7')
            xlabel(app.UIAxes_8, 'Time (s)')
            ylabel(app.UIAxes_8, 'Temp (C)')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_8.YLim = [10 40];
            app.UIAxes_9 = uiaxes(app.UIFigure);
            title(app.UIAxes_9, 'Core Temp')
            xlabel(app.UIAxes_9, 'Time (s)')
            ylabel(app.UIAxes_9, 'Temp (C)')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_9.YLim = [10 40];
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DesiredVestTemperatureCLabel = uilabel(app.UIFigure);
            app.DesiredVestTemperatureCLabel.Text = 'Desired Vest Temperature (C)';
            app.DesiredVestTemperatureCEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesiredVestTemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @DesiredVestTemperatureCEditFieldValueChanged, true);
            app.CoreTemperatureCEditFieldLabel = uilabel(app.UIFigure);
            app.CoreTemperatureCEditFieldLabel.Text = 'Core Temperature (C)';
            app.CoreTemperatureCEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoreTemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @CoreTemperatureCEditFieldValueChanged, true);
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Disconnected';
            app.PortDropDownLabel = uilabel(app.UIFigure);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.PortDropDown.Items = {};
            app.PortDropDown.ValueChangedFcn = createCallbackFcn(app, @PortDropDownValueChanged, true);
            app.PortDropDown.Value = {};
            app.StatusLamp_2 = uilamp(app.UIFigure);
            app.StatusLabel_2 = uilabel(app.UIFigure);
            app.StatusLabel_2.Text = 'Large Cell';
            app.StatusLamp_3 = uilamp(app.UIFigure);
            app.StatusLabel_3 = uilabel(app.UIFigure);
            app.StatusLabel_3.Text = 'Small Cell';
            app.ExportCSVButton = uibutton(app.UIFigure, 'push');
            app.ExportCSVButton.ButtonPushedFcn = createCallbackFcn(app, @onDataLoggingExportCSVButtonClicked, true);
            app.ExportCSVButton.Text = 'Export CSV';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Thermal_Vest_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end