classdef Thermal_Vest_Water_Tank_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StatusLabel_6                   matlab.ui.control.Label
        StatusLamp_5                    matlab.ui.control.Lamp
        StatusLabel_5                   matlab.ui.control.Label
        StatusLamp_4                    matlab.ui.control.Lamp
        Sensor5CEditField               matlab.ui.control.NumericEditField
        Sensor5CEditFieldLabel          matlab.ui.control.Label
        WaterTemperatureCEditFieldLabel  matlab.ui.control.Label
        Sensor4CEditField               matlab.ui.control.NumericEditField
        Sensor4CEditFieldLabel          matlab.ui.control.Label
        Sensor3CEditField               matlab.ui.control.NumericEditField
        Sensor3CEditFieldLabel          matlab.ui.control.Label
        Sensor2CEditField               matlab.ui.control.NumericEditField
        Sensor2CEditFieldLabel          matlab.ui.control.Label
        Sensor1CEditField               matlab.ui.control.NumericEditField
        Sensor1CEditFieldLabel          matlab.ui.control.Label
        WaterTemperatureCEditField      matlab.ui.control.NumericEditField
        StatusLabel_3                   matlab.ui.control.Label
        StatusLamp_3                    matlab.ui.control.Lamp
        StatusLabel_2                   matlab.ui.control.Label
        StatusLamp_2                    matlab.ui.control.Lamp
        PortDropDown                    matlab.ui.control.DropDown
        PortLabel                       matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
        CoreTemperatureCEditField       matlab.ui.control.NumericEditField
        CoreTemperatureCEditFieldLabel  matlab.ui.control.Label
        SetupPanel                      matlab.ui.container.Panel
        StatusLabel_4                   matlab.ui.control.Label
        DesiredWaterTemperatureCEditField  matlab.ui.control.NumericEditField
        DesiredVestTemperatureCLabel    matlab.ui.control.Label
        ExportCSVButton                 matlab.ui.control.Button
        ConnectButton                   matlab.ui.control.Button
        CellStatusPanel                 matlab.ui.container.Panel
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Properties that correspond to variables
    properties (Access = private)
        s               % Serial object
        isConnected     % Boolean indicating if Arduino is connected

        waterTemp       % X-axis data for plot
        waterTemp_Down
        %waterTemp2       % X-axis data for plot
        s1Temp          % Sensor 1 temperature
        s2Temp          % Sensor 2 temperature
        s3Temp          % Sensor 3 temperature
        s4Temp          % Sensor 4 temperature
        s5Temp          % Sensor 5 temperature
        coreTemp        % Core temperature

        yTime           % Y-axis data for plot
        %yTime2           % Y-axis data for plot
        plotLine        % Handle to plot line object
        plotLine2        % Handle to plot line object
        plotLine3       % Handle to plot line object
        plotLine4        % Handle to plot line object
        plotLine5        % Handle to plot line object
        plotLine6        % Handle to plot line object
        plotLine7        % Handle to plot line object
        plotLine8        % Handle to plot line object
        plotLine9        % Handle to plot line object

        counter = -1;

        dataStor

        wsindex=1;
        flag=0;
        slowUpdateInterval = 5; % second
        previousSlowUpdateTime = 0; % second
        slowUpdateCoreTempXList;
        slowUpdateCoreTempYList;
        slowUpdateS1TempXList;
        slowUpdateS1TempYList;
        slowUpdateS2TempXList;
        slowUpdateS2TempYList;
        slowUpdateS3TempXList;
        slowUpdateS3TempYList;
        slowUpdateS4TempXList;
        slowUpdateS4TempYList;
        slowUpdateS5TempXList;
        slowUpdateS5TempYList;
        slowUpdateWaterTempXList;
        slowUpdateWaterTempYList;
    end

    % Callbacks for App components
    methods (Access = private)

        % Button pushed function: ConnectButton
        %         function ConnectButtonPushed(app, event)
        %             if ~app.isConnected
        %                 % Open the serial port and configure it
        %                 app.s = serialport("COM4", 115200);
        %                 configureTerminator(app.s, "CR/LF");
        %
        %                 % Update status
        %                 app.StatusLabel.Text = "Connected";
        %                 app.StatusLamp.Color = 'g';
        %
        %                 % Start reading and plotting data
        %                 app.startReadData();
        %
        %                 % Update isConnected property
        %                 app.isConnected = true;
        %
        %             end
        %         end

        % Button pushed function: DisconnectButton
        %         function DisconnectButtonPushed(app, event)
        %             if app.isConnected
        %                 % Close the serial port
        %                 close(ancestor(app.s, 'figure'));
        %
        %
        %
        %                 % Update status
        %                 app.StatusLabel.Text = "Disconnected";
        %                 app.StatusLamp.Color = 'r';
        %
        %                 % Update isConnected property
        %                 app.isConnected = false;
        %             end
        %         end
        
    end

    % Methods that run on startup
    methods (Access = private)

        % Read and plot data in a loop
        function startReadData(app)

            while isvalid(app)
                % Read data from the serial port
                
                try
                    data = readline(app.s);
%                     disp(length(data))

                    % Split the data into two parts (X and Y)
                    xandy = split(data, ",");

                    % Convert the data to numeric values
                    currentTemp = str2double(xandy(2));

                    s1 = str2double(xandy(4));
                    s2 = str2double(xandy(5));
                    s3 = str2double(xandy(6));
                    s4 = str2double(xandy(7));
                    s5 = str2double(xandy(8));

                    setpoint = str2double(xandy(13));

                    cellstate1 = str2double(xandy(9));
                    cellstate2 = str2double(xandy(10));
                    cellstate3 = str2double(xandy(11));
                    cellstate4 = str2double(xandy(12));

                    coreT = str2double(xandy(3));

                    currentTime = str2double(xandy(1));

                    % Store data


                    app.dataStor(end+1,:) = [currentTime,currentTemp,coreT,s1,s2,s3,s4,s5,cellstate1,cellstate2,cellstate3,cellstate4,setpoint];
%                     disp(app.dataStor);

                    % Add the data to the plot

                    app.waterTemp = [app.waterTemp(2:end), currentTemp];
                    app.s1Temp = [app.s1Temp(2:end), s1];
                    app.s2Temp = [app.s2Temp(2:end), s2];
                    app.s3Temp = [app.s3Temp(2:end), s3];
                    app.s4Temp = [app.s4Temp(2:end), s4];
                    app.s5Temp = [app.s5Temp(2:end), s5];
                    
                    app.coreTemp = [app.coreTemp(2:end), coreT];
                    %app.waterTemp(end+1) = currentTemp;

%                     app.s1Temp(end+1) = s1;
%                     app.s2Temp(end+1) = s2;
%                     app.s3Temp(end+1) = s3;
%                     app.s4Temp(end+1) = s4;
%                     app.s5Temp(end+1) = s5;
%                     app.s6Temp(end+1) = s6;
%                     app.s7Temp(end+1) = s7;

%                     app.coreTemp(end+1) = coreT;

%                     app.yTime(end+1) = time;
                    app.yTime = [app.yTime(2:end), currentTime];
%                     disp(app.yTime2);
%                     disp(app.waterTemp);

                    % Update the plot
%                     plot(app.UIAxes, app.yTime2, app.waterTemp2)
                    %disp([min(app.yTime2),max(app.yTime2)])
                    timeMin = max([0,currentTime-5]);
                    timeMax = max([1,currentTime+5]);
                    
%                     if (app.counter == -1 || app.counter >= 6000)
%                         app.waterTemp_Down = [app.WaterTemp_Down(2:end), app.waterTemp(end)];
% 
%                        
%                        app.counter = 0;
%                     end
                    % set(app.plotLine,'xdata',app.yTime,'ydata',app.waterTemp);
                    % set(app.UIAxes,'Xlim',[timeMin,timeMax]);
                    % app.UIAxes.XTick = [timeMin,0.5*(timeMin + timeMax),timeMax];

                    % set(app.plotLine2,'xdata',app.yTime,'ydata',app.s1Temp);
                    % set(app.UIAxes_2,'Xlim',[timeMin,timeMax]);
                    % app.UIAxes_2.XTick = [timeMin,0.5*(timeMin + timeMax),timeMax];
                    % 
                    % set(app.plotLine3,'xdata',app.yTime,'ydata',app.s2Temp);
                    % set(app.UIAxes_3,'Xlim',[timeMin,timeMax]);
                    % app.UIAxes_3.XTick = [timeMin,0.5*(timeMin + timeMax),timeMax];
                    % 
                    % set(app.plotLine4,'xdata',app.yTime,'ydata',app.s3Temp);
                    % set(app.UIAxes_4,'Xlim',[timeMin,timeMax]);
                    % app.UIAxes_4.XTick = [timeMin,0.5*(timeMin + timeMax),timeMax];
                    % 
                    % set(app.plotLine5,'xdata',app.yTime,'ydata',app.s4Temp);
                    % set(app.UIAxes_5,'Xlim',[timeMin,timeMax]);
                    % app.UIAxes_5.XTick = [timeMin,0.5*(timeMin + timeMax),timeMax];
                    % 
                    % set(app.plotLine6,'xdata',app.yTime,'ydata',app.s5Temp);
                    % set(app.UIAxes_6,'Xlim',[timeMin,timeMax]);
                    % app.UIAxes_6.XTick = [timeMin,0.5*(timeMin + timeMax),timeMax];
                    
                    loc = find(isnan(app.slowUpdateCoreTempXList),1);
                    if loc == 1
                        app.slowUpdateWaterTempXList(1) = 0;
                        app.slowUpdateWaterTempYList(1) = currentTemp;
                        app.slowUpdateS1TempXList(1) = 0;
                        app.slowUpdateS1TempYList(1) = s1;
                        app.slowUpdateS2TempXList(1) = 0;
                        app.slowUpdateS2TempYList(1) = s2;
                        app.slowUpdateS3TempXList(1) = 0;
                        app.slowUpdateS3TempYList(1) = s3;
                        app.slowUpdateS4TempXList(1) = 0;
                        app.slowUpdateS4TempYList(1) = s4;
                        app.slowUpdateS5TempXList(1) = 0;
                        app.slowUpdateS5TempYList(1) = s5;
                        app.slowUpdateCoreTempXList(1) = 0;
                        app.slowUpdateCoreTempYList(1) = coreT;
                        %
                        set(app.UIAxes,'Xlim',[0,app.slowUpdateInterval * 10]);
                        set(app.UIAxes_2,'Xlim',[0,app.slowUpdateInterval * 10]);
                        set(app.UIAxes_3,'Xlim',[0,app.slowUpdateInterval * 10]);
                        set(app.UIAxes_4,'Xlim',[0,app.slowUpdateInterval * 10]);
                        set(app.UIAxes_5,'Xlim',[0,app.slowUpdateInterval * 10]);
                        set(app.UIAxes_6,'Xlim',[0,app.slowUpdateInterval * 10]);
                        set(app.UIAxes_9,'Xlim',[0,app.slowUpdateInterval * 10]);
                    elseif ~isempty(loc) && loc ~= 1
                        if currentTime - app.previousSlowUpdateTime >= app.slowUpdateInterval
                            app.slowUpdateWaterTempXList(loc) = app.previousSlowUpdateTime + app.slowUpdateInterval;
                            app.slowUpdateWaterTempYList(loc) = currentTemp;
                            app.slowUpdateS1TempXList(loc) = app.previousSlowUpdateTime + app.slowUpdateInterval;
                            app.slowUpdateS1TempYList(loc) = s1;
                            app.slowUpdateS2TempXList(loc) = app.previousSlowUpdateTime + app.slowUpdateInterval;
                            app.slowUpdateS2TempYList(loc) = s2;
                            app.slowUpdateS3TempXList(loc) = app.previousSlowUpdateTime + app.slowUpdateInterval;
                            app.slowUpdateS3TempYList(loc) = s3;
                            app.slowUpdateS4TempXList(loc) = app.previousSlowUpdateTime + app.slowUpdateInterval;
                            app.slowUpdateS4TempYList(loc) = s4;
                            app.slowUpdateS5TempXList(loc) = app.previousSlowUpdateTime + app.slowUpdateInterval;
                            app.slowUpdateS5TempYList(loc) = s5;
                            app.slowUpdateCoreTempXList(loc) = app.previousSlowUpdateTime + app.slowUpdateInterval;
                            app.slowUpdateCoreTempYList(loc) = coreT;
                            %
                            app.previousSlowUpdateTime = app.previousSlowUpdateTime + app.slowUpdateInterval;
                            set(app.UIAxes,'Xlim',[0,app.slowUpdateInterval * 10]);
                            set(app.UIAxes_2,'Xlim',[0,app.slowUpdateInterval * 10]);
                            set(app.UIAxes_3,'Xlim',[0,app.slowUpdateInterval * 10]);
                            set(app.UIAxes_4,'Xlim',[0,app.slowUpdateInterval * 10]);
                            set(app.UIAxes_5,'Xlim',[0,app.slowUpdateInterval * 10]);
                            set(app.UIAxes_6,'Xlim',[0,app.slowUpdateInterval * 10]);
                            set(app.UIAxes_9,'Xlim',[0,app.slowUpdateInterval * 10]);
                        end
                    elseif isempty(loc)
                        if currentTime - app.previousSlowUpdateTime >= app.slowUpdateInterval
                            app.slowUpdateWaterTempXList = [app.slowUpdateWaterTempXList, app.previousSlowUpdateTime + app.slowUpdateInterval];
                            app.slowUpdateWaterTempYList = [app.slowUpdateWaterTempYList, currentTemp];
                            app.slowUpdateS1TempXList = [app.slowUpdateS1TempXList, app.previousSlowUpdateTime + app.slowUpdateInterval];
                            app.slowUpdateS1TempYList = [app.slowUpdateS1TempYList, s1];
                            app.slowUpdateS2TempXList = [app.slowUpdateS1TempXList, app.previousSlowUpdateTime + app.slowUpdateInterval];
                            app.slowUpdateS2TempYList = [app.slowUpdateS1TempYList, s2];
                            app.slowUpdateS3TempXList = [app.slowUpdateS3TempXList, app.previousSlowUpdateTime + app.slowUpdateInterval];
                            app.slowUpdateS3TempYList = [app.slowUpdateS3TempYList, s3];
                            app.slowUpdateS4TempXList = [app.slowUpdateS4TempXList, app.previousSlowUpdateTime + app.slowUpdateInterval];
                            app.slowUpdateS4TempYList = [app.slowUpdateS4TempYList, s4];
                            app.slowUpdateS5TempXList = [app.slowUpdateS5TempXList, app.previousSlowUpdateTime + app.slowUpdateInterval];
                            app.slowUpdateS5TempYList = [app.slowUpdateS5TempYList, s5];
                            app.slowUpdateCoreTempXList = [app.slowUpdateCoreTempXList, app.previousSlowUpdateTime + app.slowUpdateInterval];
                            app.slowUpdateCoreTempYList = [app.slowUpdateCoreTempYList, coreT];
                            %
                            app.previousSlowUpdateTime = app.previousSlowUpdateTime + app.slowUpdateInterval;
                            % water temp
                            set(app.UIAxes,'XTickMode','auto','XTickLabelMode','auto')
                            if app.UIAxes.XTick(end) ~= app.slowUpdateWaterTempXList(end)
                                nDigits = ceil(log10(abs(double(fix(app.slowUpdateWaterTempXList(end))))+1));
                                distance = abs(app.slowUpdateWaterTempXList(end) - app.UIAxes.XTick(end)) / (app.slowUpdateWaterTempXList(end) - app.UIAxes.XTick(1)) * app.UIAxes.Position(3);
                                if distance >= 9 * (nDigits + 1) % each digit of 16px number is about 9px
                                    app.UIAxes.XTick = [app.UIAxes.XTick, app.slowUpdateWaterTempXList(end)];
                                else
                                    app.UIAxes.XTick = [app.UIAxes.XTick(1:end-1), app.slowUpdateWaterTempXList(end)];
                                end
                            end
                            set(app.UIAxes,'Xlim',[0,app.slowUpdateWaterTempXList(end)]);
                            % S1
                            set(app.UIAxes_2,'XTickMode','auto','XTickLabelMode','auto')
                            if app.UIAxes_2.XTick(end) ~= app.slowUpdateS1TempXList(end)
                                nDigits = ceil(log10(abs(double(fix(app.slowUpdateS1TempXList(end))))+1));
                                distance = abs(app.slowUpdateS1TempXList(end) - app.UIAxes_2.XTick(end)) / (app.slowUpdateS1TempXList(end) - app.UIAxes_2.XTick(1)) * app.UIAxes_2.Position(3);
                                if distance >= 9 * (nDigits + 1) % each digit of 16px number is about 9px
                                    app.UIAxes_2.XTick = [app.UIAxes_2.XTick, app.slowUpdateS1TempXList(end)];
                                else
                                    app.UIAxes_2.XTick = [app.UIAxes_2.XTick(1:end-1), app.slowUpdateS1TempXList(end)];
                                end
                            end
                            set(app.UIAxes_2,'Xlim',[0,app.slowUpdateS1TempXList(end)]);
                            % S2
                            set(app.UIAxes_3,'XTickMode','auto','XTickLabelMode','auto')
                            if app.UIAxes_3.XTick(end) ~= app.slowUpdateS2TempXList(end)
                                nDigits = ceil(log10(abs(double(fix(app.slowUpdateS2TempXList(end))))+1));
                                distance = abs(app.slowUpdateS2TempXList(end) - app.UIAxes_3.XTick(end)) / (app.slowUpdateS2TempXList(end) - app.UIAxes_3.XTick(1)) * app.UIAxes_3.Position(3);
                                if distance >= 9 * (nDigits + 1) % each digit of 16px number is about 9px
                                    app.UIAxes_3.XTick = [app.UIAxes_3.XTick, app.slowUpdateS2TempXList(end)];
                                else
                                    app.UIAxes_3.XTick = [app.UIAxes_3.XTick(1:end-1), app.slowUpdateS2TempXList(end)];
                                end
                            end
                            set(app.UIAxes_3,'Xlim',[0,app.slowUpdateS2TempXList(end)]);
                            % S3
                            set(app.UIAxes_4,'XTickMode','auto','XTickLabelMode','auto')
                            if app.UIAxes_4.XTick(end) ~= app.slowUpdateS3TempXList(end)
                                nDigits = ceil(log10(abs(double(fix(app.slowUpdateS3TempXList(end))))+1));
                                distance = abs(app.slowUpdateS3TempXList(end) - app.UIAxes_4.XTick(end)) / (app.slowUpdateS3TempXList(end) - app.UIAxes_4.XTick(1)) * app.UIAxes_4.Position(3);
                                if distance >= 9 * (nDigits + 1) % each digit of 16px number is about 9px
                                    app.UIAxes_4.XTick = [app.UIAxes_4.XTick, app.slowUpdateS3TempXList(end)];
                                else
                                    app.UIAxes_4.XTick = [app.UIAxes_4.XTick(1:end-1), app.slowUpdateS3TempXList(end)];
                                end
                            end
                            set(app.UIAxes_4,'Xlim',[0,app.slowUpdateS3TempXList(end)]);
                            % S4
                            set(app.UIAxes_5,'XTickMode','auto','XTickLabelMode','auto')
                            if app.UIAxes_5.XTick(end) ~= app.slowUpdateS4TempXList(end)
                                nDigits = ceil(log10(abs(double(fix(app.slowUpdateS4TempXList(end))))+1));
                                distance = abs(app.slowUpdateS4TempXList(end) - app.UIAxes_5.XTick(end)) / (app.slowUpdateS4TempXList(end) - app.UIAxes_5.XTick(1)) * app.UIAxes_5.Position(3);
                                if distance >= 9 * (nDigits + 1) % each digit of 16px number is about 9px
                                    app.UIAxes_5.XTick = [app.UIAxes_5.XTick, app.slowUpdateS4TempXList(end)];
                                else
                                    app.UIAxes_5.XTick = [app.UIAxes_5.XTick(1:end-1), app.slowUpdateS4TempXList(end)];
                                end
                            end
                            set(app.UIAxes_5,'Xlim',[0,app.slowUpdateS4TempXList(end)]);
                            % S5
                            set(app.UIAxes_6,'XTickMode','auto','XTickLabelMode','auto')
                            if app.UIAxes_6.XTick(end) ~= app.slowUpdateS5TempXList(end)
                                nDigits = ceil(log10(abs(double(fix(app.slowUpdateS5TempXList(end))))+1));
                                distance = abs(app.slowUpdateS5TempXList(end) - app.UIAxes_6.XTick(end)) / (app.slowUpdateS5TempXList(end) - app.UIAxes_6.XTick(1)) * app.UIAxes_6.Position(3);
                                if distance >= 9 * (nDigits + 1) % each digit of 16px number is about 9px
                                    app.UIAxes_6.XTick = [app.UIAxes_6.XTick, app.slowUpdateS5TempXList(end)];
                                else
                                    app.UIAxes_6.XTick = [app.UIAxes_6.XTick(1:end-1), app.slowUpdateS5TempXList(end)];
                                end
                            end
                            set(app.UIAxes_6,'Xlim',[0,app.slowUpdateS5TempXList(end)]);
                            % core temp
                            set(app.UIAxes_9,'XTickMode','auto','XTickLabelMode','auto')
                            if app.UIAxes_9.XTick(end) ~= app.slowUpdateCoreTempXList(end)
                                nDigits = ceil(log10(abs(double(fix(app.slowUpdateCoreTempXList(end))))+1));
                                distance = abs(app.slowUpdateCoreTempXList(end) - app.UIAxes_9.XTick(end)) / (app.slowUpdateCoreTempXList(end) - app.UIAxes_9.XTick(1)) * app.UIAxes_9.Position(3);
                                disp([app.slowUpdateCoreTempXList(end), app.UIAxes_9.XTick(end), nDigits, 9 * (nDigits + 1), distance])
                                if distance >= 9 * (nDigits + 1) % each digit of 16px number is about 9px
                                    app.UIAxes_9.XTick = [app.UIAxes_9.XTick, app.slowUpdateCoreTempXList(end)];
                                else
                                    app.UIAxes_9.XTick = [app.UIAxes_9.XTick(1:end-1), app.slowUpdateCoreTempXList(end)];
                                end
                            end
                            set(app.UIAxes_9,'Xlim',[0,app.slowUpdateCoreTempXList(end)]);
                        end
                    end
                    set(app.plotLine,'xdata',app.slowUpdateWaterTempXList,'ydata',app.slowUpdateWaterTempYList);
                    set(app.plotLine2,'xdata',app.slowUpdateS1TempXList,'ydata',app.slowUpdateS1TempYList);
                    set(app.plotLine3,'xdata',app.slowUpdateS2TempXList,'ydata',app.slowUpdateS2TempYList);
                    set(app.plotLine4,'xdata',app.slowUpdateS3TempXList,'ydata',app.slowUpdateS3TempYList);
                    set(app.plotLine5,'xdata',app.slowUpdateS4TempXList,'ydata',app.slowUpdateS4TempYList);
                    set(app.plotLine6,'xdata',app.slowUpdateS5TempXList,'ydata',app.slowUpdateS5TempYList);
                    set(app.plotLine9,'xdata',app.slowUpdateCoreTempXList,'ydata',app.slowUpdateCoreTempYList);
                    % set(app.UIAxes_9,'Xlim',[timeMin,timeMax]);
                    
                    % disp([app.UIAxes_9.XTick(end),app.slowUpdateCoreTempXList(end)])

%                     plot(app.UIAxes_2, app.yTime, app.s1Temp)
%                     plot(app.UIAxes_3, app.yTime, app.s2Temp)
%                     plot(app.UIAxes_4, app.yTime, app.s3Temp)
%                     plot(app.UIAxes_5, app.yTime, app.s4Temp)
%                     plot(app.UIAxes_6, app.yTime, app.s5Temp)
%                     plot(app.UIAxes_7, app.yTime, app.s6Temp)
%                     plot(app.UIAxes_8, app.yTime, app.s7Temp)
%                     plot(app.UIAxes_9, app.yTime, app.coreTemp)

                    app.CoreTemperatureCEditField.Value = coreT;
                    app.Sensor1CEditField.Value = s1;
                    app.Sensor2CEditField.Value = s2;
                    app.Sensor3CEditField.Value = s3;
                    app.Sensor4CEditField.Value = s4;
                    app.Sensor5CEditField.Value = s5;
                    app.DesiredWaterTemperatureCEditField.Value = setpoint;
                    app.WaterTemperatureCEditField.Value = currentTemp;
                    % Pause for a short period of time to prevent overloading the serial port

                    if(cellstate1 == 1)
                        app.StatusLamp_2.Color = 'g';
                    end
                    if(cellstate1 == 0)
                        app.StatusLamp_2.Color = 'r';
                    end

                    if(cellstate2 == 1)
                        app.StatusLamp_3.Color = 'g';
                    end
                    if(cellstate2 == 0)
                        app.StatusLamp_3.Color = 'r';
                    end

                    if(cellstate3 == 1)
                        app.StatusLamp_4.Color = 'g';
                    end
                    if(cellstate3 == 0)
                        app.StatusLamp_4.Color = 'r';
                    end

                    if(cellstate4 == 1)
                        app.StatusLamp_5.Color = 'g';
                    end
                    if(cellstate4 == 0)
                        app.StatusLamp_5.Color = 'r';
                    end
                    

                    if(app.flag == 1)
                        formatOut = 'mm-dd-yy(HH_MM_ss)';
                        t = datestr(now,formatOut);
                        proj = 'CoolingVestData';
                        filetype = '.csv';
                        fileName = strcat(t,proj,filetype);
                        dataTable = array2table(app.dataStor);
                        dataTable.Properties.VariableNames = {'Time (s)','Water temperature (C)','Core Temperature (C)','Sensor 1 (C)','Sensor 2 (C)','Sensor 3 (C)','Sensor 4 (C)','Sensor 5 (C)','Cell 1','Cell 2','Cell 3','Cell 4','Setpoint'};
                        writetable(dataTable,fileName);
                        app.flag = 0;
                    end
                    pause(0.01);
                catch e
                    % rethrow(e)
                    % fprintf(2,'The identifier was:\n%s',e.identifier);
                    % fprintf(2,'There was an error! The message was:\n%s',e.message);
                    % break;
                end

                app.counter = app.counter + 1;

            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize properties
            app.isConnected = false;
            app.waterTemp = zeros(1,10);
            app.waterTemp_Down = zeros(1,20);
            app.s1Temp = zeros(1,10);
            app.s2Temp = zeros(1,10);
            app.s3Temp = zeros(1,10);
            app.s4Temp = zeros(1,10);
            app.s5Temp = zeros(1,10);
            app.coreTemp = zeros(1,10);
%             app.yTime = [];
            app.yTime = zeros(1,10);
            %
            app.slowUpdateWaterTempXList = nan(1,10);
            app.slowUpdateWaterTempYList = nan(1,10);
            app.slowUpdateS1TempXList = nan(1,10);
            app.slowUpdateS1TempYList = nan(1,10);
            app.slowUpdateS2TempXList = nan(1,10);
            app.slowUpdateS2TempYList = nan(1,10);
            app.slowUpdateS3TempXList = nan(1,10);
            app.slowUpdateS3TempYList = nan(1,10);
            app.slowUpdateS4TempXList = nan(1,10);
            app.slowUpdateS4TempYList = nan(1,10);
            app.slowUpdateS5TempXList = nan(1,10);
            app.slowUpdateS5TempYList = nan(1,10);
            app.slowUpdateCoreTempXList = nan(1,10);
            app.slowUpdateCoreTempYList = nan(1,10);
            
            
            serialPorts = serialportlist("available");
            app.PortDropDown.Items=serialPorts;

            % Create the plot
            app.plotLine = plot(app.UIAxes, app.yTime, app.waterTemp,'o-');
            app.plotLine2 = plot(app.UIAxes_2, app.yTime, app.s1Temp,'o-');
            app.plotLine3 = plot(app.UIAxes_3, app.yTime, app.s2Temp,'o-');
            app.plotLine4 = plot(app.UIAxes_4, app.yTime, app.s3Temp,'o-');
            app.plotLine5 = plot(app.UIAxes_5, app.yTime, app.s4Temp,'o-');
            app.plotLine6 = plot(app.UIAxes_6, app.yTime, app.s5Temp,'o-');
            app.plotLine9 = plot(app.UIAxes_9, app.yTime, app.coreTemp,'o-');
        end

        % Value changed function: DesiredWaterTemperatureCEditField
        function DesiredWaterTemperatureCEditFieldValueChanged(app, event)
            value = app.DesiredWaterTemperatureCEditField.Value;
            valueStr = sprintf('%.6f', value);
            msg = strcat('0', valueStr);
            write(app.s, msg, "string");

        end

        % Value changed function: CoreTemperatureCEditField
        function CoreTemperatureCEditFieldValueChanged(app, event)
            %value = app.CoreTemperatureCEditField.Value;
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if isvalid(app)

                app.wsindex=1;
                openport=app.PortDropDown.Value;

                if ~app.isConnected
                    % Open the serial port and configure it
                    app.s = serialport(openport, 115200);
                    configureTerminator(app.s, "CR/LF");

                    % Update status
                    app.StatusLabel.Text = "Connected";

                    % Start reading and plotting data
                    app.startReadData();
                    disp(123123);
                    % Update isConnected property
                    if isvalid(app)
                        app.isConnected = true;
                    end

                end
            end
        end

        % Callback function
        function DisconnectButtonPushed(app, event)
            if app.isConnected
                % Close the serial port
                close(ancestor(app.s, 'figure'));

                % Update status
                app.StatusLabel.Text = "Disconnected";
                app.StatusLamp.Color = 'r';

                % Update isConnected property
                app.isConnected = false;
            end
        end

        % Callback function
        function PcoefficientEditFieldValueChanged(app, event)
            value = app.PcoefficientEditField.Value;
            valueStr = sprintf('%.6f', value);
            msg = strcat('1', valueStr);
            write(app.s, msg, "string");
        end

        % Callback function
        function IcoefficientEditFieldValueChanged(app, event)
            value = app.IcoefficientEditField.Value;
            valueStr = sprintf('%.6f', value);
            msg = strcat('2', valueStr);
            write(app.s, msg, "string");
        end

        % Callback function
        function DcoefficientEditFieldValueChanged(app, event)
            value = app.DcoefficientEditField.Value;
            valueStr = sprintf('%.6f', value);
            msg = strcat('3', valueStr);
            write(app.s, msg, "string");
        end

        % Value changed function: PortDropDown
        function PortDropDownValueChanged(app, event)
            %value = app.PortDropDown.Value;
        end

        % Button pushed function: ExportCSVButton
        function onDataLoggingExportCSVButtonClicked(app, event)
            app.flag = 1;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.isConnected = 0;
            delete(app)
            
        end

        % Value changed function: Sensor5CEditField
        function Sensor5CEditFieldValueChanged(app, event)
            %value = app.Sensor5CEditField.Value;
            
        end

        % Value changed function: Sensor4CEditField
        function Sensor4CEditFieldValueChanged(app, event)
            %value = app.Sensor4CEditField.Value;
            
        end

        % Value changed function: Sensor3CEditField
        function Sensor3CEditFieldValueChanged(app, event)
            %value = app.Sensor3CEditField.Value;
            
        end

        % Value changed function: Sensor2CEditField
        function Sensor2CEditFieldValueChanged(app, event)
            %value = app.Sensor2CEditField.Value;
            
        end

        % Value changed function: Sensor1CEditField
        function Sensor1CEditFieldValueChanged(app, event)
            %value = app.Sensor1CEditField.Value;
            
        end

        % Value changed function: WaterTemperatureCEditField
        function WaterTemperatureCEditFieldValueChanged(app, event)
            %value = app.WaterTemperatureCEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Water Temperature')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Temp (C)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [0 30];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Sensor 1')
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'Temp (C)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.YLim = [10 40];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Sensor 2')
            xlabel(app.UIAxes_3, 'Time (s)')
            ylabel(app.UIAxes_3, 'Temp (C)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.YLim = [10 40];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Sensor 3')
            xlabel(app.UIAxes_4, 'Time (s)')
            ylabel(app.UIAxes_4, 'Temp (C)')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.YLim = [10 40];
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Sensor 4')
            xlabel(app.UIAxes_5, 'Time (s)')
            ylabel(app.UIAxes_5, 'Temp (C)')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.YLim = [10 40];
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Sensor 5')
            xlabel(app.UIAxes_6, 'Time (s)')
            ylabel(app.UIAxes_6, 'Temp (C)')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.YLim = [10 40];
            app.UIAxes_9 = uiaxes(app.UIFigure);
            title(app.UIAxes_9, 'Core Temp')
            xlabel(app.UIAxes_9, 'Time (s)')
            ylabel(app.UIAxes_9, 'Temp (C)')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_9.YLim = [15 40];
            app.CellStatusPanel = uipanel(app.UIFigure);
            app.CellStatusPanel.Title = 'Cell Status:';
            app.SetupPanel = uipanel(app.UIFigure);
            app.SetupPanel.Title = 'Setup:';
            app.ConnectButton = uibutton(app.SetupPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ExportCSVButton = uibutton(app.SetupPanel, 'push');
            app.ExportCSVButton.ButtonPushedFcn = createCallbackFcn(app, @onDataLoggingExportCSVButtonClicked, true);
            app.ExportCSVButton.Text = 'Export CSV';
            app.DesiredVestTemperatureCLabel = uilabel(app.SetupPanel);
            app.DesiredVestTemperatureCLabel.Text = 'Desired Water Temperature (C)';
            app.DesiredWaterTemperatureCEditField = uieditfield(app.SetupPanel, 'numeric');
            app.DesiredWaterTemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @DesiredWaterTemperatureCEditFieldValueChanged, true);
            app.StatusLabel_4 = uilabel(app.SetupPanel);
            app.StatusLabel_4.Text = 'Status:';
            app.CoreTemperatureCEditFieldLabel = uilabel(app.UIFigure);
            app.CoreTemperatureCEditFieldLabel.Text = 'Core Temperature (C)';
            app.CoreTemperatureCEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoreTemperatureCEditField.RoundFractionalValues = 'on';
            app.CoreTemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @CoreTemperatureCEditFieldValueChanged, true);
            app.CoreTemperatureCEditField.Editable = 'off';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Disconnected';
            app.PortLabel = uilabel(app.UIFigure);
            app.PortLabel.Text = 'Port:';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.PortDropDown.Items = {};
            app.PortDropDown.ValueChangedFcn = createCallbackFcn(app, @PortDropDownValueChanged, true);
            app.PortDropDown.Value = {};
            app.StatusLamp_2 = uilamp(app.UIFigure);
            app.StatusLabel_2 = uilabel(app.UIFigure);
            app.StatusLabel_2.Text = 'Water Tank';
            app.StatusLamp_3 = uilamp(app.UIFigure);
            app.StatusLabel_3 = uilabel(app.UIFigure);
            app.StatusLabel_3.Text = 'Water Tank';
            app.WaterTemperatureCEditField = uieditfield(app.UIFigure, 'numeric');
            app.WaterTemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @WaterTemperatureCEditFieldValueChanged, true);
            app.WaterTemperatureCEditField.Editable = 'off';
            app.Sensor1CEditFieldLabel = uilabel(app.UIFigure);
            app.Sensor1CEditFieldLabel.Text = 'Sensor 1 (C)';
            app.Sensor1CEditField = uieditfield(app.UIFigure, 'numeric');
            app.Sensor1CEditField.ValueChangedFcn = createCallbackFcn(app, @Sensor1CEditFieldValueChanged, true);
            app.Sensor1CEditField.Editable = 'off';
            app.Sensor2CEditFieldLabel = uilabel(app.UIFigure);
            app.Sensor2CEditFieldLabel.Text = 'Sensor 2 (C)';
            app.Sensor2CEditField = uieditfield(app.UIFigure, 'numeric');
            app.Sensor2CEditField.ValueChangedFcn = createCallbackFcn(app, @Sensor2CEditFieldValueChanged, true);
            app.Sensor2CEditField.Editable = 'off';
            app.Sensor3CEditFieldLabel = uilabel(app.UIFigure);
            app.Sensor3CEditFieldLabel.Text = 'Sensor 3 (C)';
            app.Sensor3CEditField = uieditfield(app.UIFigure, 'numeric');
            app.Sensor3CEditField.ValueChangedFcn = createCallbackFcn(app, @Sensor3CEditFieldValueChanged, true);
            app.Sensor3CEditField.Editable = 'off';
            app.Sensor4CEditFieldLabel = uilabel(app.UIFigure);
            app.Sensor4CEditFieldLabel.Text = 'Sensor 4 (C)';
            app.Sensor4CEditField = uieditfield(app.UIFigure, 'numeric');
            app.Sensor4CEditField.ValueChangedFcn = createCallbackFcn(app, @Sensor4CEditFieldValueChanged, true);
            app.Sensor4CEditField.Editable = 'off';
            app.WaterTemperatureCEditFieldLabel = uilabel(app.UIFigure);
            app.WaterTemperatureCEditFieldLabel.Text = 'Water Temperature (C)';
            app.Sensor5CEditFieldLabel = uilabel(app.UIFigure);
            app.Sensor5CEditFieldLabel.Text = 'Sensor 5 (C)';
            app.Sensor5CEditField = uieditfield(app.UIFigure, 'numeric');
            app.Sensor5CEditField.ValueChangedFcn = createCallbackFcn(app, @Sensor5CEditFieldValueChanged, true);
            app.Sensor5CEditField.Editable = 'off';
            app.StatusLamp_4 = uilamp(app.UIFigure);
            app.StatusLabel_5 = uilabel(app.UIFigure);
            app.StatusLabel_5.Text = 'Cell 1';
            app.StatusLamp_5 = uilamp(app.UIFigure);
            app.StatusLabel_6 = uilabel(app.UIFigure);
            app.StatusLabel_6.Text = 'Cell 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Thermal_Vest_Water_Tank_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end